classdef GMNMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        Menu_3              matlab.ui.container.Menu
        Panel_3             matlab.ui.container.Panel
        Button_12           matlab.ui.control.Button
        Button_10           matlab.ui.control.Button
        EditField_5         matlab.ui.control.NumericEditField
        Label_20            matlab.ui.control.Label
        EditField_4         matlab.ui.control.NumericEditField
        EditField_4Label    matlab.ui.control.Label
        Label_19            matlab.ui.control.Label
        Button_8            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Panel_2             matlab.ui.container.Panel
        Button_11           matlab.ui.control.Button
        EditField_4Label_2  matlab.ui.control.Label
        EditField_9         matlab.ui.control.NumericEditField
        Label_23            matlab.ui.control.Label
        Button_9            matlab.ui.control.Button
        EditField_8         matlab.ui.control.NumericEditField
        Label_22            matlab.ui.control.Label
        EditField_7         matlab.ui.control.NumericEditField
        Label_21            matlab.ui.control.Label
        EditField_6         matlab.ui.control.NumericEditField
        EditField_6Label    matlab.ui.control.Label
        Button_5            matlab.ui.control.Button
        EditField_3         matlab.ui.control.NumericEditField
        Label_18            matlab.ui.control.Label
        EditField_2         matlab.ui.control.NumericEditField
        Label_17            matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        DatePicker          matlab.ui.control.DatePicker
        Label_24            matlab.ui.control.Label
        Button_4            matlab.ui.control.Button
        EditField           matlab.ui.control.NumericEditField
        Label_15            matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        ta7                 matlab.ui.control.NumericEditField
        ta6                 matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        ta5                 matlab.ui.control.NumericEditField
        Label_5             matlab.ui.control.Label
        ta4                 matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        ta3                 matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        ta2                 matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        ta1                 matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        Label_16            matlab.ui.control.Label
        calculateButton     matlab.ui.control.Button
        TextArea_6          matlab.ui.control.TextArea
        Label_13            matlab.ui.control.Label
        TextArea_5          matlab.ui.control.TextArea
        Label_12            matlab.ui.control.Label
        TextArea_4          matlab.ui.control.TextArea
        Label_11            matlab.ui.control.Label
        TextArea_3          matlab.ui.control.TextArea
        Label_10            matlab.ui.control.Label
        TextArea_2          matlab.ui.control.TextArea
        Label_9             matlab.ui.control.Label
        TextArea_1          matlab.ui.control.TextArea
        Label_8             matlab.ui.control.Label
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        degreesLabel_2      matlab.ui.control.Label
        degreesLabel        matlab.ui.control.Label
        nTLabel_4           matlab.ui.control.Label
        nTLabel_3           matlab.ui.control.Label
        nTLabel_2           matlab.ui.control.Label
        nTLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        input_lat 
        input_lon 
        input_alt
        resolution_lat 
        resolution_lon 
        size_lat 
        size_lon 
        lats 
        lons
        len_max
        magnetic_map
        magnetic_mod 
        magnetic_dec 
        magnetic_inc
        magnetic_x
        magnetic_y
        magnetic_z
        magnetic_mod_xy
        magnetic_alts
        smooth_resolutionlat
        smooth_resolutionlon
        lats_smooth
        lons_smooth
        magnetic_inc_smooth
        magnetic_dec_smooth
        magnetic_mod_smooth
        date
        center_lat 
        center_lon 
        top_lat 
        bottom_lat 
        left_lon 
        right_lon
        len_lats
        len_lons
        X
        Y
        tmi
        len_linear
        flag
        magnetic_dec_rand_linear
        magnetic_inc_rand_linear
        magnetic_mod_rand_linear
        rand_X_linear
        rand_Y_linear
        lat_curve
        lon_curve
        lat_curve2
        lon_curve2
        x_curve
        y_curve
        bx
        by
        bz
        magnetic_declination
        magnetic_inclination
        rand_x_linear
        rand_y_linear
        flag2
        year
        day
        month
    end


    methods (Access = private)
        function init(app)
            app.input_lon = app.ta1.Value;
            app.input_lat = app.ta2.Value;
            app.input_alt=app.ta3.Value;
            app.resolution_lon = app.ta4.Value;
            app.resolution_lat =app.ta5.Value;
            app.size_lon =app.ta6.Value;
            app.size_lat =app.ta7.Value;
            
 
            if isempty(app.input_lat)
                app.input_lat = 0;
            end
            if isempty(app.input_lon)
                app.input_lon = 0;
            end
            if isempty(app.input_alt)
                app.input_alt = 0;
            end
            if isempty(app.size_lat)
                app.size_lat =180 ;
            end
            if isempty(app.size_lon)
                app.size_lon = 360;
            end
            if isempty(app.resolution_lat)
                app.resolution_lat = 18;
            end
            if isempty(app.resolution_lon)
                app.resolution_lon= 36;
            end

            % 计算经度和纬度范围
            app.center_lat = app.input_lat;
            app.center_lon = app.input_lon;
            app.top_lat = app.center_lat + app.size_lat / 2;
            app.bottom_lat = app.center_lat - app.size_lat / 2;
            app.left_lon = app.center_lon - app.size_lon / 2;
            app.right_lon = app.center_lon + app.size_lon / 2;
            % 生成经度和纬度网格
            app.lats = app.bottom_lat:app.resolution_lat:app.top_lat;
            app.lons = app.left_lon:app.resolution_lon:app.right_lon;
            app.len_lats = length(app.lats);
            app.len_lons = length(app.lons);
            
            if app.len_lats > app.len_lons   
                app.lons = linspace(app.lons(1), app.lons(end), app.len_lats);
            else
                app.lats = linspace(app.lats(1), app.lats(end), app.len_lons);
            end
            app.len_max=length(app.lats); 
            
            app.center_lat = app.input_lat;
            app.center_lon = app.input_lon;            
            app.top_lat = app.center_lat + app.size_lat / 2;
            app.bottom_lat = app.center_lat - app.size_lat / 2;  
            % if app.top_lat > 90 || app.bottom_lat < -90
            %     m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
            %     msgbox(m, "输入不合法");
            %     uiwait; % 暂停代码运行，等待用户点击消息框
            %     return; % 结束当前函数执行
            % end
            % 
            % % 判断是否超出经度范围
            % if app.left_lon < -180 || app.right_lon > 180
            %     m = sprintf('经度范围超出 [-180, 180]，请重新输入');
            %     msgbox(m, "输入不合法");
            %     uiwait; % 暂停代码运行，等待用户点击消息框
            %     return; % 结束当前函数执行
            % end
            % if app.size_lon>360
            %     m = sprintf('经度范围超出 [-180, 180]，请重新输入');
            %     msgbox(m, "输入不合法");
            %     uiwait; % 暂停代码运行，等待用户点击消息框
            %     return; % 结束当前函数执行
            % end  
            % 
            % 
            %  if app.size_lat>180
            %     m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
            %     msgbox(m, "输入不合法");
            %     uiwait; % 暂停代码运行，等待用户点击消息框
            %     return; % 结束当前函数执行
            % end          
            % 获取用户输入位置的地磁场信息
           value = app.DatePicker.Value;
           app.year=year(value);
           app.month=month(value);
           app.day=day(value);
           minDate = datetime(1900, 1, 1);
           maxDate = datetime('2029-12-31');

    % 检查选择的日期是否在允许范围内
            if app.year < 1900
                % 如果选择的日期在1900年之前，则将日期设置为最小允许日期
                app.year=1900;
                m = sprintf('年份小于1900，已将日期设为1900-%d-%d',app.month,app.day);
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            elseif app.year >2030
                % 如果选择的日期在2024年之后，则将日期设置为最大允许日期
                app.year= 2030;
                m = sprintf('年份大于2030，已将年份设为2030-%d-%d',app.month,app.day);
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等
            end

            app.date = datenum(app.year, app.month, app.day);
            [app.bx, app.by, app.bz] = igrf(app.DatePicker.Value, app.input_lat, app.input_lon, app.input_alt);%东、北、天地磁分量，量纲：nT
                        % 计算地磁场总量
            app.tmi = sqrt(app.bx^2 + app.by^2 + app.bz^2);                     
                
            % 计算磁偏角和磁倾角
            app.magnetic_declination = atan2d(app.by, app.bx);
            app.magnetic_inclination = atan2d(app.bz, sqrt(app.bx^2 + app.by^2)); 
            app.magnetic_mod_xy=ones(length(app.lats), length(app.lons));
            app.magnetic_mod = ones(length(app.lats), length(app.lons));
            app.magnetic_dec = ones(length(app.lats), length(app.lons));
            app.magnetic_inc = ones(length(app.lats), length(app.lons));
            app.magnetic_x=ones(length(app.lats), length(app.lons));
            app.magnetic_y=ones(length(app.lats), length(app.lons));
            app.magnetic_z=ones(length(app.lats), length(app.lons));
            app.magnetic_alts=app.input_alt*ones(length(app.lats), length(app.lons));
            [app.X, app.Y] = meshgrid(app.lats, app.lons);
            for i = 1:length(app.lats)
                for j = 1:length(app.lons)
                    [x, y, z] = igrf(app.DatePicker.Value, app.lats(i), app.lons(j), app.input_alt);
                    app.magnetic_mod(i, j) = sqrt(x^2 + y^2 + z^2);
                    app.magnetic_dec(i, j) = atan2d((y), (x));
                    app.magnetic_inc(i, j) = atan2d((z), sqrt((x)^2 + (y)^2));
                    app.magnetic_x(i,j)=x;
                    app.magnetic_y(i,j)=y;
                    app.magnetic_z(i,j)=z;
                    app.magnetic_mod_xy(i,j)=sqrt((x)^2 + (y)^2);
                    
                end
            end
            app.smooth_resolutionlat = app.resolution_lat/10;
            app.smooth_resolutionlon = app.resolution_lon/10;
            [app.lats_smooth, app.lons_smooth] = meshgrid(app.bottom_lat:app.smooth_resolutionlat:app.top_lat, app.left_lon:app.smooth_resolutionlon:app.right_lon);
            app.magnetic_inc_smooth = griddata(app.lats, app.lons, app.magnetic_inc, app.lats_smooth, app.lons_smooth, 'v4');
            app.magnetic_dec_smooth = griddata(app.lats, app.lons, app.magnetic_dec, app.lats_smooth, app.lons_smooth, 'v4');
            app.magnetic_mod_smooth=griddata(app.lats, app.lons, app.magnetic_mod, app.lats_smooth, app.lons_smooth, 'v4');
            % lats_smooth1=app.lats_smooth(1,:);
            % lons_smooth1=app.lons_smooth(1,:);
            app.magnetic_map=[app.X;app.Y;app.magnetic_alts;app.magnetic_x;app.magnetic_y;app.magnetic_z;app.magnetic_mod_xy;app.magnetic_mod;app.magnetic_dec;app.magnetic_inc];
        end
        
         function [bezierLat, bezierLon] = randomBezierCurve(~,n)
            figure;
            worldmap('World'); % 显示世界地图
            load coastlines; % 加载世界海岸线数据
            plotm(coastlat, coastlon, 'k'); % 绘制海岸线
        
            % 保持添加新图形元素的状态
            hold on;
        
            % 随机生成控制点
            controlPointsLat = -90 + 180 * rand(1, n + 1); % 纬度控制点
            controlPointsLon = -180 + 360 * rand(1, n + 1); % 经度控制点
            %plotm(controlPointsLat, controlPointsLon, 'ko--', 'LineWidth', 1); % 绘制控制点及其连线
        
            % 初始化贝塞尔曲线上的点
            numBezierPoints = 100;
            bezierLat = zeros(1, numBezierPoints);
            bezierLon = zeros(1, numBezierPoints);
        
            % 计算贝塞尔曲线上的点
            for i = 1:numBezierPoints
                t = (i-1) / (numBezierPoints - 1); % 当前t值（从0到1）
                % 计算n次贝塞尔曲线上的点
                for j = 0:n
                    % 计算二项式系数
                    binomialCoeff = nchoosek(n, j);
                    % 更新纬度和经度的贝塞尔曲线值
                    bezierLat(i) = bezierLat(i) + binomialCoeff * (1-t)^(n-j) * t^j * controlPointsLat(j + 1);
                    bezierLon(i) = bezierLon(i) + binomialCoeff * (1-t)^(n-j) * t^j * controlPointsLon(j + 1);
                end
            end
        
            % 绘制贝塞尔曲线
            plotm(bezierLat, bezierLon, 'b-', 'LineWidth', 2); 
        
            % 轨迹终点和起点
            plotm(bezierLat(1), bezierLon(1), 'g*', 'MarkerSize', 15);
            plotm(bezierLat(end), bezierLon(end), 'r*', 'MarkerSize', 15);
        
            % 设置图例
            legend('edge of world','Global Coastlines', 'Bezier Path', 'Starting Point', 'Ending Point');
            % 标题
            title(sprintf('%dth-degree Bezier Curve on World Map', n));
        
            % 取消保持状态
            hold off;
   
        end
        
         function [bezierLat, bezierLon] = randomBezierCurve2(~,n, startLat, startLon, endLat, endLon)
            figure;
            worldmap('World'); % 显示世界地图
            load coastlines; % 加载世界海岸线数据
            plotm(coastlat, coastlon, 'k'); % 绘制海岸线
        
            % 保持添加新图形元素的状态
            hold on;
        
            % 随机生成中间控制点
            controlPointsLat = -90 + 180 * rand(1, n - 1); % 中间纬度控制点
            controlPointsLon = -180 + 360 * rand(1, n - 1); % 中间经度控制点
        
            % 将起点和终点加入到控制点数组中
            controlPointsLat = [startLat controlPointsLat endLat];
            controlPointsLon = [startLon controlPointsLon endLon];
        
            % 初始化贝塞尔曲线上的点
            numBezierPoints = 100;
            bezierLat = zeros(1, numBezierPoints);
            bezierLon = zeros(1, numBezierPoints);
        
            % 计算贝塞尔曲线上的点
            for i = 1:numBezierPoints
                t = (i-1) / (numBezierPoints - 1); % 当前t值（从0到1）
                % 计算n次贝塞尔曲线上的点
                for j = 0:n
                    % 计算二项式系数
                    binomialCoeff = nchoosek(n, j);
                    % 更新纬度和经度的贝塞尔曲线值
                    bezierLat(i) = bezierLat(i) + binomialCoeff * (1-t)^(n-j) * t^j * controlPointsLat(j + 1);
                    bezierLon(i) = bezierLon(i) + binomialCoeff * (1-t)^(n-j) * t^j * controlPointsLon(j + 1);
                end
            end
        
            % 绘制贝塞尔曲线
            plotm(bezierLat, bezierLon, 'b-', 'LineWidth', 2); 
        
            % 轨迹终点和起点
            plotm(bezierLat(1), bezierLon(1), 'g*', 'MarkerSize', 15);
            plotm(bezierLat(end), bezierLon(end), 'r*', 'MarkerSize', 15);
        
            % 设置图例
            legend('edge of world','Global Coastlines', 'Bezier Path', 'Starting Point', 'Ending Point');
            % 标题
            title(sprintf('%dth-degree Bezier Curve on World Map', n));
        
            % 取消保持状态
            hold off;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Global_Geomagnetic_Navigation_Mapping_System(app)
            app.UIFigure.Name='Global_Geomagnetic_Navigation_Mapping_System';
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            init(app);
            if app.top_lat > 90 || app.bottom_lat < -90
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            
            % 判断是否超出经度范围
            if app.left_lon < -180 || app.right_lon > 180
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            if app.size_lon>360
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end  
          
 
             if app.size_lat>180
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
             end
             if app.year>2030||app.year<1900

                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
             end
             if app.month>12||app.year<1

                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
             end
             if app.day>31||app.day<1

                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
             end

            app.TextArea_1.Value = num2str(app.tmi);
            app.TextArea_2.Value = num2str(app.bx);
            app.TextArea_3.Value = num2str(app.by);
            app.TextArea_4.Value = num2str(app.bz);
            app.TextArea_6.Value = num2str(app.magnetic_declination);
            app.TextArea_5.Value = num2str(app.magnetic_inclination);
            
        % 定位该坐标
            figure("Name","全球定位1");
            geoscatter(app.center_lat,app.center_lon,'red','filled');
            % 创建地图对象并在地图上标记用户输入的位置和磁场方向
            figure("Name","全球定位2");
            worldmap('World');
            hold on;
            geoshow('landareas.shp', 'FaceAlpha', 0);
            geoshow(app.input_lat, app.input_lon, 'DisplayType', 'point', 'Marker', '*', 'Color', 'red', 'MarkerSize', 10);
            %quiverm(input_lat, input_lon, mby), mod(bx), 'Color', 'blue', 'LineWidth', 1.5);
            textm(app.input_lat, app.input_lon, sprintf('模值: %.4f nT', app.tmi), 'Color', 'red', 'FontSize', 10);
            title('地磁场方向示意图');


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            init(app);
            if app.top_lat > 90 || app.bottom_lat < -90
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            
            % 判断是否超出经度范围
            if app.left_lon < -180 || app.right_lon > 180
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            if app.size_lon>360
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end  
          
 
             if app.size_lat>180
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
             end
             
            sigma =app.EditField.Value ;  % 选择一个适合场景的噪声标准差
            
            % 生成和磁场模值数组相同大小的高斯噪声
            gaussian_noise_mod = 650*sigma * randn(size(app.magnetic_mod));
            gaussian_noise_dec = 1.8*sigma * randn(size(app.magnetic_mod));
            gaussian_noise_inc=  0.9*sigma * randn(size(app.magnetic_mod));
            % 将高斯噪声添加到磁场模值中
            magnetic_mod_noisy = app.magnetic_mod + gaussian_noise_mod;
            % 为磁偏角和磁倾角添加相同的噪声
            magnetic_dec_noisy = app.magnetic_dec + gaussian_noise_dec;
            magnetic_inc_noisy = app.magnetic_inc + gaussian_noise_inc;
            
            % 创建地磁场总量对比图
            figure("Name","地磁场总量对比");
            % 绘制加噪声的地磁场总量三维图
            subplot(1,2,1)
            hold on;
            surfc(app.X,app.Y, magnetic_mod_noisy, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('地磁场总量 (nT)');
            title('地磁场总量三维图(加噪声)');
            view(3);
            colorbar;
            
            % 绘制真实值的地磁场总量三维图
            subplot(1,2,2)
            hold on;
            surfc(app.X, app.Y, app.magnetic_mod, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('地磁场总量 (nT)');
            title('地磁场总量三维图(真实值)');
            view(3);
            colorbar;
            
            % 创建磁偏角对比图
            figure("Name","磁航向对比");
            % 绘制加噪声的磁偏角三维图
            subplot(1,2,1)
            hold on;
            surfc(app.X, app.Y, magnetic_dec_noisy, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁航向 (degrees)');
            title('磁航向三维图(加噪声)');
            view(3);
            colorbar;
            
            % 绘制真实值的磁偏角三维图
            subplot(1,2,2)
            hold on;
            surfc(app.X, app.Y, app.magnetic_dec, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁航向 (degrees)');
            title('磁航向三维图(真实值)');
            view(3);
            colorbar;
            
            % 创建磁倾角对比图
            figure("Name","磁倾角对比");
            % 绘制加噪声的磁倾角三维图
            subplot(1,2,1)
            hold on;
            surfc(app.X, app.Y, magnetic_inc_noisy, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁倾角 (degrees)');
            title('磁倾角三维图(加噪声)');
            view(3);
            colorbar;
            
            % 绘制真实值的磁倾角三维图
            subplot(1,2,2)
            hold on;
            surfc(app.X, app.Y, app.magnetic_inc, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁倾角 (degrees)');
            title('磁倾角三维图(真实值)');
            view(3);
            colorbar;
            writematrix(magnetic_mod_noisy,"magnetic_mod_noisy.csv");
            writematrix(magnetic_dec_noisy,"magnetic_dec_noisy.csv");
            writematrix(magnetic_inc_noisy,"magnetic_inc_noisy.csv");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            init(app);
            % 判断是否超出纬度范围
            if app.top_lat > 90 || app.bottom_lat < -90
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            
            % 判断是否超出经度范围
            if app.left_lon < -180 || app.right_lon > 180
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            if app.size_lon>360
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end  
          
 
             if app.size_lat>180
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
             end 
            
            
            % 如果没有超出范围，继续执行以下代码
            
            % 创建新的图形窗口
            figure("Name","地磁场总量三维图");
            subplot(1,2,1)
            hold on;
            mesh(app.X, app.Y, app.magnetic_mod, 'EdgeColor', 'interp','LineWidth',2.5); 
            %surfc(X, Y, magnetic_mod, 'EdgeColor', 'interp'); 
            xlabel('纬度');
            ylabel('经度');
            zlabel('地磁场总量 (nT)');
            title('地磁场总量三维图(真实值)');
            view(3);
            colorbar;
            % 绘制地磁场总量的三维图
            subplot(1,2,2)
            hold on;
            %mesh(lats_smooth, lons_smooth, magnetic_mod_smooth, 'EdgeColor', 'none'); 
            surfc(app.lats_smooth, app.lons_smooth, app.magnetic_mod_smooth, 'EdgeColor', 'interp'); 
            xlabel('纬度');
            ylabel('经度');
            zlabel('地磁场总量 (nT)');
            title('地磁场总量三维图(由MATLAB渲染后)');
            view(3);
            colorbar;
            
            % 创建新的图形窗口
            figure("Name","磁航向三维图");
            % 绘制磁偏角的对比图
            subplot(1,2,1)
            hold on;
            mesh(app.lats, app.lons, app.magnetic_dec, 'EdgeColor', 'interp','LineWidth',2.5);
            %surfc(lats, lons, magnetic_dec, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁航向 (degrees)');
            title('磁航向三维图(真实值)');
            view(3);
            colorbar;
            % 绘制磁偏角的对比图
            subplot(1,2,2)
            hold on;
            %mesh(lats_smooth, lons_smooth, magnetic_dec_smooth, 'EdgeColor', 'none');
            surfc(app.lats_smooth, app.lons_smooth, app.magnetic_dec_smooth, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁航向 (degrees)');
            title('磁航向三维图(由MATLAB渲染后)');
            view(3);
            colorbar;
            
            % 创建新的图形窗口
            figure("Name", "磁倾角三维图");
            
            % 绘制磁倾角的对比图
            subplot(1,2,1)
            hold on;
            %surfc(lats, lons, magnetic_inc, 'EdgeColor', 'interp'); 
            mesh(app.lats, app.lons, app.magnetic_inc, 'EdgeColor', 'interp','LineWidth',2.5); 
            
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁倾角 (degrees)');
            title('磁倾角三维图(真实值)');
            view(3);
            colorbar;
            
            % 绘制磁倾角的对比图
            subplot(1,2,2)
            hold on;
            %mesh(lats_smooth, lons_smooth, magnetic_inc_smooth, 'EdgeColor', 'none'); 
            surfc(app.lats_smooth, app.lons_smooth, app.magnetic_inc_smooth, 'EdgeColor', 'interp');
            xlabel('纬度');
            ylabel('经度');
            zlabel('磁倾角 (degrees)');
            title('磁倾角三维图(由MATLAB渲染后)');
            view(3);
            colorbar;            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 创建进度对话框
            d = uiprogressdlg(app.UIFigure, ...
                'Title', '请稍候', ...
                'Message', '正在生成磁图...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off');
        
            d.Value = 0.05;
        
            % 判断输入合法性
            if app.top_lat > 90 || app.bottom_lat < -90
                msgbox('纬度范围超出 [-90, 90]，请重新输入', "输入不合法");
                uiwait;
                close(d);
                return;
            end
        
            if app.left_lon < -180 || app.right_lon > 180 || app.size_lon > 360
                msgbox('经度范围超出 [-180, 180]，请重新输入', "输入不合法");
                uiwait;
                close(d);
                return;
            end
        
            if app.size_lat > 180
                msgbox('纬度范围超出 [-90, 90]，请重新输入', "输入不合法");
                uiwait;
                close(d);
                return;
            end
        
            % ========== 开始绘图 ==========
            % 1. 模值图绘制
            d.Message = '正在绘制磁地图模值...';
            d.Value = 0.2;
        
            figure("Name","磁地图模值");
            subplot(1, 2, 1);
            hold on;
            worldmap([app.bottom_lat app.top_lat], [app.left_lon app.right_lon]);
            geoshow(app.lats_smooth, app.lons_smooth, app.magnetic_mod_smooth, 'DisplayType', 'texturemap');
            geoshow(app.input_lat, app.input_lon, "Marker","*",'Color','black', 'MarkerSize', 10);
            geoshow('landareas.shp', 'FaceAlpha', 0);
            title('磁地图模值纹理图');
            colorbar;
        
            subplot(1, 2, 2);
            hold on;
            worldmap([app.bottom_lat app.top_lat], [app.left_lon app.right_lon]);
            geoshow('landareas.shp', 'FaceColor', 'none');
            geoshow(app.input_lat, app.input_lon, 'DisplayType', 'point', 'Marker', '*', 'Color', 'red', 'MarkerSize', 10);
            contourm(app.lats, app.lons, app.magnetic_mod,'LineWidth',2);
            title('磁地图模值等值线图');
            colorbar;
        
            % 2. 磁偏角图绘制
            d.Message = '正在绘制磁偏角图...';
            d.Value = 0.5;
        
            figure("Name","磁偏角");
            subplot(1, 2, 1);
            hold on;
            worldmap([app.bottom_lat app.top_lat], [app.left_lon app.right_lon]);
            geoshow(app.lats_smooth, app.lons_smooth, app.magnetic_dec_smooth, 'DisplayType', 'texturemap');
            geoshow(app.input_lat, app.input_lon, "Marker","*",'Color','black', 'MarkerSize', 10);
            geoshow('landareas.shp', 'FaceAlpha', 0);
            title('磁航向纹理图');
            colorbar;
        
            subplot(1, 2, 2);
            hold on;
            worldmap([app.bottom_lat app.top_lat], [app.left_lon app.right_lon]);
            geoshow('landareas.shp', 'FaceColor', 'none');
            geoshow(app.input_lat, app.input_lon, 'DisplayType', 'point', 'Marker', '*', 'Color', 'red', 'MarkerSize', 10);
            contourm(app.lats, app.lons, app.magnetic_dec, 'LineWidth', 2);
            title('磁航向等值线图');
            colorbar;
        
            % 3. 磁倾角图绘制
            d.Message = '正在绘制磁倾角图...';
            d.Value = 0.8;
        
            figure("Name","磁倾角");
            subplot(1, 2, 1);
            hold on;
            worldmap([app.bottom_lat app.top_lat], [app.left_lon app.right_lon]);
            geoshow(app.lats_smooth, app.lons_smooth, app.magnetic_inc_smooth, 'DisplayType', 'texturemap');
            geoshow(app.input_lat, app.input_lon, "Marker","*",'Color','black', 'MarkerSize', 10);
            geoshow('landareas.shp', 'FaceAlpha', 0);
            title('磁倾角纹理图');
            colorbar;
        
            subplot(1, 2, 2);
            hold on;
            worldmap([app.bottom_lat app.top_lat], [app.left_lon app.right_lon]);
            geoshow('landareas.shp', 'FaceColor', 'none');
            geoshow(app.input_lat, app.input_lon, 'DisplayType', 'point', 'Marker', '*', 'Color', 'red', 'MarkerSize', 10);
            contourm(app.lats, app.lons, app.magnetic_inc, 'LineWidth', 2);
            title('磁倾角等值线图');
            colorbar;
        
            % 结束
            d.Message = '绘图完成！';
            d.Value = 1;
            pause(0.5); % 小延迟便于用户看到完成状态
            close(d);
        end

        % Callback function
        function TabButtonDown(app, event)
            a=sprintf('全球地磁失真图生成与演示系统v1.0V1.0\n开发人员：韩文乐，陈坤\n指导老师联系邮箱：liugongxu@xidian.edu.cn\nSLCMN\tTeam:感知，定位，通信，构图与导航团队，该团队本着以学生为中心和师生共同成长的理念，集导学/科研/毕设/竞赛/教改/人文六维为一体，更好助力学生成长、长才');
            msgbox(a,'关于和联系我们');
        end

        % Callback function
        function DropDownOpening(app, event)
        
        end

        % Callback function
        function DropDownClicked(app, event)
           
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             init(app);
             if app.top_lat > 90 || app.bottom_lat < -90
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            
            % 判断是否超出经度范围
            if app.left_lon < -180 || app.right_lon > 180
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            if app.size_lon>360
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end  
          
 
             if app.size_lat>180
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
             end

             
             writematrix(app.magnetic_map,"magnetic_map.csv");
             writematrix(app.X,"X.csv");
             writematrix(app.Y,"Y.csv");
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            init(app);
            if app.top_lat > 90 || app.bottom_lat < -90
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            
            % 判断是否超出经度范围
            if app.left_lon < -180 || app.right_lon > 180
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            if app.size_lon>360
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end  
          
 
             if app.size_lat>180
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end          
            
            rand_x =randperm(app.len_max);
            rand_y =randperm(app.len_max);
            rand_lat = ones(1, app.len_max);
            rand_lon = ones(1, app.len_max);
            rand_quo=rand_y./rand_x;
            % 将 rand_x 和 rand_y 合并成一个矩阵
            data_matrix = [rand_quo; rand_x; rand_y]';
            % 使用 sortrows 函数对矩阵按照第一列（rand_quo）进行排序
            sorted_data = sortrows(data_matrix, 1);
            % 从排序后的矩阵中提取排序后的 rand_x 和 rand_y
            rand_x = sorted_data(:, 2)';
            rand_y = sorted_data(:, 3)';
            
            for i=1:app.len_max
                rand_lat(i) = app.lats(rand_x(i));
                rand_lon(i) = app.lons(rand_y(i));  
            end
            app.rand_x_linear=rand_lat;
            app.rand_y_linear=rand_lon;
            [rand_X,rand_Y]=meshgrid(rand_lat,rand_lon);
            % 创建一个新的图形窗口
            figure;
            % 使用worldmap函数显示全球地图
            worldmap([min(rand_lat) max(rand_lat)],[min(rand_lon) max(rand_lon)]);
            % 获取地图数据
            land = shaperead('landareas', 'UseGeoCoords', true);
            % 使用geoshow来绘制地图
            geoshow(land, 'FaceColor', [0.5 0.7 0.5]);
            % 持续等待增加新的图形部分
            hold on;
            % 使用plotm绘制随机轨迹，'r-o'表示红色圆圈及连线
            plotm(rand_lat, rand_lon, 'r-o', 'LineWidth', 1.2);
            title('随机轨迹与世界地图');
            figure;
            hold on;
            plot(rand_lat,rand_lon,'r-o','LineWidth', 1.2);
            xlabel('纬度');
            ylabel('经度');
            xlim([min(rand_lat) max(rand_lat)]);
            ylim([min(rand_lon) max(rand_lon)]);
            hold off;
            [xq, yq] = meshgrid(linspace(min(rand_lat), max(rand_lat), 100),linspace(min(rand_lon), max(rand_lon), 100));
            
            
            magnetic_mod_xy_rand=ones(length(rand_x), length(rand_y));
            magnetic_mod_rand = ones(length(rand_x), length(rand_y));
            magnetic_dec_rand = ones(length(rand_x), length(rand_y));
            magnetic_inc_rand = ones(length(rand_x), length(rand_y));
            magnetic_x_rand=ones(length(rand_x), length(rand_y));
            magnetic_y_rand=ones(length(rand_x), length(rand_y));
            magnetic_z_rand=ones(length(rand_x), length(rand_y));
            magnetic_alts_rand=app.input_alt*ones(length(rand_x), length(rand_y));
            for i = 1:length(rand_x)
                for j = 1:length(rand_y)
                    [x, y, z] = igrf(app.DatePicker.Value, rand_lat(i), rand_lon(j), app.input_alt);
                    magnetic_mod_rand(i, j) = sqrt(x^2 + y^2 + z^2);
                    magnetic_dec_rand(i, j) = atan2d((y), (x));
                    magnetic_inc_rand(i, j) = atan2d((z), sqrt((x)^2 + (y)^2));
                    magnetic_x_rand(i,j)=x;
                    magnetic_y_rand(i,j)=y;
                    magnetic_z_rand(i,j)=z;
                    magnetic_mod_xy_rand(i,j)=sqrt((x)^2 + (y)^2);
                    
                end
            end
            magnetic_map_rand=[rand_X;rand_Y;magnetic_alts_rand;magnetic_x_rand;magnetic_y_rand;magnetic_z_rand;magnetic_mod_xy_rand;magnetic_mod_rand;magnetic_dec_rand;magnetic_inc_rand];
            writematrix(magnetic_map_rand,"magnetic_map_linear.csv");


            magnetic_inc_rand_grid = griddata(rand_lat, rand_lon, magnetic_inc_rand, xq, yq, 'v4');
            magnetic_dec_rand_grid = griddata(rand_lat, rand_lon, magnetic_dec_rand, xq, yq, 'v4');
            magnetic_mod_rand_grid = griddata(rand_lat, rand_lon, magnetic_mod_rand, xq, yq, 'v4');
            % rand_lat=xq(1,:);
            % rand_lon=(yq(:,1))';
            
            figure;
            subplot(1,3,1);
            hold on;
            mesh(xq,yq,magnetic_inc_rand_grid);
            plot3(rand_X,rand_Y,magnetic_inc_rand,'r.','MarkerFaceColor','r','MarkerSize',10);
            %plot(rand_lat,rand_lon,'LineWidth',2,'Color','black');
            xlabel('经度');
            ylabel('纬度');
            zlabel('磁倾角 (degrees)');
            title('磁倾角三维图）');
            xlim([min(rand_lat) max(rand_lat)]);
            ylim([min(rand_lon) max(rand_lon)]);
            view(3);
            subplot(1,3,2);
            hold on;
            mesh(xq,yq,magnetic_mod_rand_grid);
            plot3(rand_X,rand_Y,magnetic_mod_rand,'r.','MarkerFaceColor','r','MarkerSize',10);
            plot(rand_lat,rand_lon,'LineWidth',2,'Color','black');
            xlabel('经度');
            ylabel('纬度');
            zlabel('磁模值 (nT)');
            title('磁模值三维图）');
            xlim([min(rand_lat) max(rand_lat)]);
            ylim([min(rand_lon) max(rand_lon)]);
            view(3);
            subplot(1,3,3);
            hold on;
            mesh(xq,yq,magnetic_dec_rand_grid);
            plot3(rand_X,rand_Y,magnetic_dec_rand,'r.','MarkerFaceColor','r','MarkerSize',10);
            %plot(rand_lat,rand_lon,'LineWidth',2,'Color','black');
            %scatter3(rand_X,rand_Y,magnetic_dec_rand,'red','filled','LineWidth',0.3);
            xlabel('经度');
            ylabel('纬度');
            zlabel('磁航向 (degrees)');
            title('磁航向三维图）');
            xlim([min(rand_lat) max(rand_lat)]);
            ylim([min(rand_lon) max(rand_lon)]);
            view(3);
            app.len_linear=length(rand_X);
            
            app.magnetic_dec_rand_linear=magnetic_dec_rand;
            app.magnetic_mod_rand_linear=magnetic_mod_rand;
            app.magnetic_inc_rand_linear=magnetic_inc_rand;
            app.rand_X_linear=rand_X;
            app.rand_Y_linear=rand_Y;
            app.rand_x_linear=rand_lat;
            app.rand_y_linear=rand_lon;
            app.flag=1;
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             value = app.EditField_5.Value; 
             if value==0
                m=sprintf('复杂度不能为零！');
                msgbox(m,"注意");
             else
                 [app.lat_curve,app.lon_curve]=randomBezierCurve(app,value);
                 app.flag=2;
             end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
           
           
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
% --- 开始判断 app.flag ---
            if app.flag==1
                % 创建进度条对话框
                d = uiprogressdlg(app.UIFigure,'Title','请稍候',...
                                'Message','正在初始化数据...', 'Indeterminate','on', 'Cancelable', 'on');
                if d.CancelRequested, return; end % 允许用户取消

                init(app);
                if d.CancelRequested, return; end

                d.Message = '正在准备线性路径数据...';
                rand_lat=app.rand_x_linear;
                rand_lon=app.rand_y_linear;
                magnetic_dec_rand=app.magnetic_dec_rand_linear;
                magnetic_mod_rand=app.magnetic_mod_rand_linear;
                magnetic_inc_rand=app.magnetic_inc_rand_linear;
                sigma =app.EditField_4.Value ;  % 选择一个适合场景的噪声标准差

                d.Message = '正在进行网格化插值 (griddata)...';
                pause(0.01); % 允许UI更新消息
                [xq, yq] = meshgrid(linspace(min(rand_lat), max(rand_lat), 100),linspace(min(rand_lon), max(rand_lon), 100));
                if d.CancelRequested, return; end
                magnetic_inc_rand_grid = griddata(rand_lat, rand_lon, magnetic_inc_rand, xq, yq, 'v4');
                if d.CancelRequested, return; end
                magnetic_dec_rand_grid = griddata(rand_lat, rand_lon, magnetic_dec_rand, xq, yq, 'v4');
                if d.CancelRequested, return; end
                magnetic_mod_rand_grid = griddata(rand_lat, rand_lon, magnetic_mod_rand, xq, yq, 'v4');
                if d.CancelRequested, return; end

                d.Message = '正在生成高斯噪声...';
                % 生成和磁场模值数组相同大小的高斯噪声
                gaussian_noise_mod = 650*sigma * randn(size(magnetic_mod_rand_grid));
                gaussian_noise_dec = 1.8*sigma * randn(size(magnetic_dec_rand_grid));
                gaussian_noise_inc=  0.9*sigma * randn(size(magnetic_inc_rand_grid));
                gaussian_noise_mod2 = 650*sigma * randn(size(magnetic_mod_rand));
                gaussian_noise_dec2 = 1.8*sigma * randn(size(magnetic_dec_rand));
                gaussian_noise_inc2=  0.9*sigma * randn(size(magnetic_inc_rand));
                % 将高斯噪声添加到磁场模值中
                magnetic_mod_noisy = magnetic_mod_rand_grid + gaussian_noise_mod;
                magnetic_dec_noisy = magnetic_dec_rand_grid + gaussian_noise_dec;
                magnetic_inc_noisy = magnetic_inc_rand_grid + gaussian_noise_inc;
                magnetic_mod_noisy2 = magnetic_mod_rand + gaussian_noise_mod2;
                magnetic_dec_noisy2 = magnetic_dec_rand + gaussian_noise_dec2;
                magnetic_inc_noisy2 = magnetic_inc_rand + gaussian_noise_inc2;
                if d.CancelRequested, return; end

                d.Message = '正在生成地磁场总量对比图...';
                figure("Name","地磁场总量对比");
                % 绘制加噪声的地磁场总量三维图
                subplot(1,2,1)
                hold on;
                mesh(xq,yq, magnetic_mod_noisy );
                plot3(app.rand_X_linear,app.rand_Y_linear, magnetic_mod_noisy2,'r.','MarkerFaceColor','r','MarkerSize',10);
                % plot(app.rand_x_linear,app.rand_x_linear); % 注意: 这行代码可能需要检查其意图
                xlabel('纬度'); ylabel('经度'); zlabel('地磁场总量 (nT)');
                title('地磁场总量三维图(加噪声)'); view(3); colorbar;
                
                % 绘制真实值的地磁场总量三维图
                subplot(1,2,2)
                hold on;
                mesh(xq,yq, magnetic_mod_rand_grid);
                plot3(app.rand_X_linear,app.rand_Y_linear, app.magnetic_mod_rand_linear,'r.','MarkerFaceColor','r','MarkerSize',10);
                xlabel('纬度'); ylabel('经度'); zlabel('地磁场总量 (nT)');
                title('地磁场总量三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end

                d.Message = '正在生成磁偏角对比图...';
                figure("Name","磁对比"); % 注意: 图形名称可能应为 "磁偏角对比"
                % ... (磁偏角和磁倾角的绘图代码类似地添加 d.Message 更新和取消检查) ...
                % 为简洁起见，后续绘图部分省略了 d.Message 和 if d.CancelRequested 的重复添加，
                % 但在实际应用中，应在每个可能耗时的绘图figure创建前后都检查和更新。
                subplot(1,2,1);
                hold on;
                mesh(xq,yq, magnetic_dec_noisy );
                plot3(app.rand_X_linear,app.rand_Y_linear, magnetic_dec_noisy2,'r.','MarkerFaceColor','r','MarkerSize',10);
                xlabel('纬度'); ylabel('经度'); zlabel('磁航向 (degrees)');
                title('磁航向三维图(加噪声)'); view(3); colorbar;

                subplot(1,2,2);
                hold on;
                mesh(xq,yq, magnetic_dec_rand_grid);
                plot3(app.rand_X_linear, app.rand_Y_linear, app.magnetic_dec_rand_linear,'r.','MarkerFaceColor','r');
                xlabel('纬度'); ylabel('经度'); zlabel('磁航向 (degrees)');
                title('磁航向三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end

                d.Message = '正在生成磁倾角对比图...';
                figure("Name","磁倾角对比");
                subplot(1,2,1);
                hold on;
                mesh(xq,yq, magnetic_inc_noisy);
                plot3(app.rand_X_linear,app.rand_Y_linear, magnetic_inc_noisy2,'r.','MarkerFaceColor','r','MarkerSize',10);
                xlabel('纬度'); ylabel('经度'); zlabel('磁倾角 (degrees)');
                title('磁倾角三维图(加噪声)'); view(3); colorbar;

                subplot(1,2,2);
                hold on;
                mesh(xq,yq, magnetic_inc_rand_grid);
                plot3(app.rand_X_linear, app.rand_Y_linear, app.magnetic_inc_rand_linear,'r.','MarkerFaceColor','r','MarkerSize',10);
                xlabel('纬度'); ylabel('经度'); zlabel('磁倾角 (degrees)');
                title('磁倾角三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end
                
                d.Message = '正在保存CSV文件...';
                writematrix(magnetic_mod_noisy,"magnetic_mod_noisy_linear.csv");
                writematrix(magnetic_dec_noisy,"magnetic_dec_noisy_linear.csv");
                writematrix(magnetic_inc_noisy,"magnetic_inc_noisy_linear.csv");
                
                close(d); % 关闭进度条

            elseif app.flag==2 % 注意这里用 elseif，假设flag只有1或2这两个有效值
                % 创建进度条对话框
                d = uiprogressdlg(app.UIFigure,'Title','请稍候',...
                                'Message','正在初始化数据...', 'Indeterminate','on', 'Cancelable','on');
                if d.CancelRequested, return; end

                init(app);
                if d.CancelRequested, return; end

                len_curve = length(app.lat_curve);
                if len_curve == 0
                    close(d);
                    msgbox('曲线数据为空，无法处理！', '错误', 'error');
                    return;
                end

                d.Message = '正在准备数据矩阵...';
                d.Indeterminate = 'off'; % 切换到确定模式，为接下来的循环做准备
                d.Value = 0; % 初始化进度

                magnetic_mod_xy_rand=ones(len_curve, len_curve);
                magnetic_mod_rand = ones(len_curve, len_curve);
                magnetic_dec_rand = ones(len_curve, len_curve);
                magnetic_inc_rand = ones(len_curve, len_curve);
                magnetic_x_rand=ones(len_curve, len_curve);
                magnetic_y_rand=ones(len_curve, len_curve);
                magnetic_z_rand=ones(len_curve, len_curve);
                magnetic_alts_rand=app.input_alt*ones(len_curve, len_curve);

                d.Message = '正在计算曲线上的地磁数据 (IGRF)...';
                total_calculations = len_curve * len_curve;
                current_calculation = 0;

                for i = 1:len_curve
                    if d.CancelRequested, break; end % 检查外层循环取消
                    for j = 1:len_curve
                        if d.CancelRequested, break; end % 检查内层循环取消

                        [x, y, z] = igrf(app.DatePicker.Value, app.lat_curve(i), app.lon_curve(j), app.input_alt);
                        magnetic_mod_rand(i, j) = sqrt(x^2 + y^2 + z^2);
                        magnetic_dec_rand(i, j) = atan2d((y), (x));
                        magnetic_inc_rand(i, j) = atan2d((z), sqrt((x)^2 + (y)^2));
                        magnetic_x_rand(i,j)=x;
                        magnetic_y_rand(i,j)=y;
                        magnetic_z_rand(i,j)=z;
                        magnetic_mod_xy_rand(i,j)=sqrt((x)^2 + (y)^2);
                        
                        current_calculation = current_calculation + 1;
                        if mod(current_calculation, max(1,floor(total_calculations/100))) == 0 % 每处理1%或至少1个点更新一次
                            d.Value = current_calculation / total_calculations;
                            d.Message = sprintf('正在计算曲线地磁数据... (%d%%)', floor(d.Value*100));
                        end
                    end
                    if d.CancelRequested, break; end % 从内层循环跳出后再次检查
                end
                if d.CancelRequested, close(d); return; end
                d.Value = 1; % 确保进度条达到100%

                [rand_X,rand_Y]=meshgrid(app.lat_curve,app.lon_curve);
                magnetic_map_rand=[rand_X;rand_Y;magnetic_alts_rand;magnetic_x_rand;magnetic_y_rand;magnetic_z_rand;magnetic_mod_xy_rand;magnetic_mod_rand;magnetic_dec_rand;magnetic_inc_rand];
                
                d.Message = '正在生成高斯噪声...';
                d.Indeterminate = 'on'; % 对于后续步骤，如果不易跟踪，可以切回不确定模式
                sigma =app.EditField_4.Value ;
                gaussian_noise_mod = 650*sigma * randn(size(magnetic_mod_rand));
                gaussian_noise_dec = 1.8*sigma * randn(size(magnetic_mod_rand));
                gaussian_noise_inc=  0.9*sigma * randn(size(magnetic_mod_rand));
                magnetic_mod_noisy = magnetic_mod_rand + gaussian_noise_mod;
                magnetic_dec_noisy = magnetic_dec_rand + gaussian_noise_dec;
                magnetic_inc_noisy = magnetic_inc_rand + gaussian_noise_inc;
                if d.CancelRequested, close(d); return; end

                % --- 后续的绘图和保存文件部分，与 flag==1 类似，添加 Message 更新和取消检查 ---
                d.Message = '正在生成地磁场总量对比图...';
                figure("Name","地磁场总量对比");
                subplot(1,2,1);
                hold on;
                mesh(rand_X,rand_Y, magnetic_mod_noisy);
                plot3(rand_X,rand_Y, magnetic_mod_noisy,'b.','MarkerFaceColor','b','MarkerSize',1);
                plot(app.lat_curve,app.lon_curve,'LineWidth',2,'Color','black'); % 注意: 2D plot on 3D axes
                xlabel('纬度'); ylabel('经度'); zlabel('地磁场总量 (nT)');
                title('地磁场总量三维图(加噪声)'); view(3); colorbar;

                subplot(1,2,2);
                hold on;
                mesh(rand_X,rand_Y, magnetic_mod_rand);
                plot3(rand_X,rand_Y, magnetic_mod_rand,'b.','MarkerFaceColor','b','MarkerSize',1);
                plot(app.lat_curve,app.lon_curve,'LineWidth',2,'Color','black'); % 注意: 2D plot on 3D axes
                xlabel('纬度'); ylabel('经度'); zlabel('地磁场总量 (nT)');
                title('地磁场总量三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end
                
                % (类似地为磁偏角和磁倾角图添加进度消息和检查)
                d.Message = '正在生成磁偏角对比图...';
                figure("Name","磁偏角对比");
                subplot(1,2,1);
                hold on;
                mesh(rand_X,rand_Y, magnetic_dec_noisy);
                plot3(rand_X,rand_Y, magnetic_dec_noisy,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁航向 (degrees)');
                title('磁航向三维图(加噪声)'); view(3); colorbar;

                subplot(1,2,2);
                hold on;
                mesh(rand_X,rand_Y, magnetic_dec_rand);
                plot3(rand_X,rand_Y, magnetic_dec_rand,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁航向 (degrees)');
                title('磁航向三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end

                d.Message = '正在生成磁倾角对比图...';
                figure("Name","磁倾角对比");
                subplot(1,2,1);
                hold on;
                mesh(rand_X,rand_Y, magnetic_inc_noisy);
                plot3(rand_X,rand_Y, magnetic_inc_noisy,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁倾角 (degrees)');
                title('磁倾角三维图(加噪声)'); view(3); colorbar;

                subplot(1,2,2);
                hold on;
                mesh(rand_X,rand_Y, magnetic_inc_rand);
                plot3(rand_X,rand_Y, magnetic_inc_rand,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁倾角 (degrees)');
                title('磁倾角三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end

                d.Message = '正在保存CSV文件...';
                writematrix(magnetic_map_rand,"magnetic_map_rand_curve.csv");
                writematrix(magnetic_mod_noisy,"magnetic_mod_noisy_curve.csv");
                writematrix(magnetic_dec_noisy,"magnetic_dec_noisy_curve.csv");
                writematrix(magnetic_inc_noisy,"magnetic_inc_noisy_curve.csv");
                
                % 这两行代码的意图需要确认，rand_X 和 rand_Y 是网格，而 lat_curve/lon_curve 通常是一维路径
                % app.lat_curve=rand_X; 
                % app.lon_curve=rand_Y;
                
                close(d); % 关闭进度条
            else
                % 如果 app.flag 不是1或2，则执行这里的逻辑
                % 确保在所有分支中都正确处理了可能已创建的进度条 d (如果适用)
                m=sprintf('请先生成轨迹！');
                msgbox(m,"注意");
            end


        end

        % Value changed function: ta1
        function ta1ValueChanged(app, event)
           
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             value = app.EditField_6.Value;
             startLat =app.EditField_2.Value;
             startLon =app.EditField_3.Value;
             endLat=app.EditField_7.Value;
             endLon=app.EditField_8.Value;
             if startLat > 90 || endLat < -90||startLat <- 90|| endLat < -90
                m = sprintf('纬度范围超出 [-90, 90]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
            
            % 判断是否超出经度范围
            if startLon < -180 || endLon > 180|| startLon> 180|| endLon < -180
                m = sprintf('经度范围超出 [-180, 180]，请重新输入');
                msgbox(m, "输入不合法");
                uiwait; % 暂停代码运行，等待用户点击消息框
                return; % 结束当前函数执行
            end
             if value==0
                m=sprintf('复杂度不能为零！');
                msgbox(m,"注意");
             else
                 [app.lat_curve,app.lon_curve]=randomBezierCurve2(app,value,startLat, startLon, endLat, endLon);
                 app.flag2=3;
             end


        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
if app.flag2==3 % 确保这是您期望的条件变量名，之前是 app.flag
                % 创建进度条对话框
                d = uiprogressdlg(app.UIFigure,'Title','请稍候',...
                                'Message','正在初始化...', 'Indeterminate','on', 'Cancelable', 'on');
                if d.CancelRequested, return; end % 允许用户取消

                d.Message = '正在初始化核心数据 (init)...';
                init(app);
                if d.CancelRequested, close(d); return; end

                len_curve = length(app.lat_curve);
                if len_curve == 0
                    close(d); % 如果曲线为空，关闭进度条并提示错误
                    msgbox('曲线数据为空，无法处理！', '错误', 'error');
                    return;
                end
                
                d.Message = '正在准备数据矩阵...';
                pause(0.01); % 给UI一点时间更新消息

                magnetic_mod_xy_rand=ones(len_curve, len_curve);
                magnetic_mod_rand = ones(len_curve, len_curve);
                magnetic_dec_rand = ones(len_curve, len_curve);
                magnetic_inc_rand = ones(len_curve, len_curve);
                magnetic_x_rand=ones(len_curve, len_curve);
                magnetic_y_rand=ones(len_curve, len_curve);
                magnetic_z_rand=ones(len_curve, len_curve);
                magnetic_alts_rand=app.input_alt*ones(len_curve, len_curve);
                if d.CancelRequested, close(d); return; end

                % --- IGRF 计算循环 ---
                d.Message = '正在计算曲线上的地磁数据 (IGRF)...';
                d.Indeterminate = 'off'; % 切换到确定模式
                d.Value = 0;             % 初始化进度

                total_calculations = len_curve * len_curve;
                current_calculation = 0;

                for i = 1:len_curve
                    if d.CancelRequested, break; end % 检查外层循环取消
                    for j = 1:len_curve
                        if d.CancelRequested, break; end % 检查内层循环取消

                        [x, y, z] = igrf(app.DatePicker.Value, app.lat_curve(i), app.lon_curve(j), app.input_alt);
                        magnetic_mod_rand(i, j) = sqrt(x^2 + y^2 + z^2);
                        magnetic_dec_rand(i, j) = atan2d((y), (x));
                        magnetic_inc_rand(i, j) = atan2d((z), sqrt((x)^2 + (y)^2));
                        magnetic_x_rand(i,j)=x;
                        magnetic_y_rand(i,j)=y;
                        magnetic_z_rand(i,j)=z;
                        magnetic_mod_xy_rand(i,j)=sqrt((x)^2 + (y)^2);
                        
                        current_calculation = current_calculation + 1;
                        % 更新进度条 (例如每处理1%或至少每10个点更新一次，避免过于频繁)
                        if mod(current_calculation, max(1, floor(total_calculations/100))) == 0 || current_calculation == total_calculations
                            d.Value = current_calculation / total_calculations;
                            d.Message = sprintf('计算地磁数据... (%d%%)', floor(d.Value*100));
                        end
                    end
                    if d.CancelRequested, break; end % 从内层循环跳出后再次检查
                end
                
                if d.CancelRequested % 如果在循环中取消
                    close(d); 
                    return; 
                end
                d.Value = 1; % 确保循环结束后进度条达到100%
                d.Indeterminate = 'on'; % 后续步骤切换回不确定模式

                d.Message = '正在准备地磁图数据...';
                [rand_X,rand_Y]=meshgrid(app.lat_curve,app.lon_curve);
                magnetic_map_rand=[rand_X;rand_Y;magnetic_alts_rand;magnetic_x_rand;magnetic_y_rand;magnetic_z_rand;magnetic_mod_xy_rand;magnetic_mod_rand;magnetic_dec_rand;magnetic_inc_rand];
                if d.CancelRequested, close(d); return; end

                d.Message = '正在保存原始地磁图数据 (CSV)...';
                writematrix(magnetic_map_rand,"magnetic_map_rand_curve_n.csv");
                if d.CancelRequested, close(d); return; end
                
                d.Message = '正在生成高斯噪声...';
                sigma =app.EditField_4.Value ;  % 选择一个适合场景的噪声标准差
                gaussian_noise_mod = 650*sigma * randn(size(magnetic_mod_rand));
                gaussian_noise_dec = 1.8*sigma * randn(size(magnetic_mod_rand));
                gaussian_noise_inc=  0.9*sigma * randn(size(magnetic_mod_rand));
                magnetic_mod_noisy = magnetic_mod_rand + gaussian_noise_mod;
                magnetic_dec_noisy = magnetic_dec_rand + gaussian_noise_dec;
                magnetic_inc_noisy = magnetic_inc_rand + gaussian_noise_inc;
                if d.CancelRequested, close(d); return; end
                
                % --- 绘图部分 ---
                d.Message = '正在生成地磁场总量对比图...';
                figure("Name","地磁场总量对比");
                subplot(1,2,1); hold on;
                mesh(rand_X,rand_Y, magnetic_mod_noisy);
                plot3(rand_X,rand_Y, magnetic_mod_noisy,'b.','MarkerFaceColor','b','MarkerSize',1);
                plot(app.lat_curve,app.lon_curve,'LineWidth',2,'Color','black');
                xlabel('纬度'); ylabel('经度'); zlabel('地磁场总量 (nT)');
                title('地磁场总量三维图(加噪声)'); view(3); colorbar;
                
                subplot(1,2,2); hold on;
                mesh(rand_X,rand_Y, magnetic_mod_rand);
                plot3(rand_X,rand_Y, magnetic_mod_rand,'b.','MarkerFaceColor','b','MarkerSize',1);
                plot(app.lat_curve,app.lon_curve,'LineWidth',2,'Color','black');
                xlabel('纬度'); ylabel('经度'); zlabel('地磁场总量 (nT)');
                title('地磁场总量三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end

                d.Message = '正在生成磁偏角对比图...';
                figure("Name","磁偏角对比");
                subplot(1,2,1); hold on;
                mesh(rand_X,rand_Y, magnetic_dec_noisy);
                plot3(rand_X,rand_Y, magnetic_dec_noisy,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁航向 (degrees)');
                title('磁航向三维图(加噪声)'); view(3); colorbar;
                
                subplot(1,2,2); hold on;
                mesh(rand_X,rand_Y, magnetic_dec_rand);
                plot3(rand_X,rand_Y, magnetic_dec_rand,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁航向 (degrees)');
                title('磁航向三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end

                d.Message = '正在生成磁倾角对比图...';
                figure("Name","磁倾角对比");
                subplot(1,2,1); hold on;
                mesh(rand_X,rand_Y, magnetic_inc_noisy);
                plot3(rand_X,rand_Y, magnetic_inc_noisy,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁倾角 (degrees)');
                title('磁倾角三维图(加噪声)'); view(3); colorbar;
                
                subplot(1,2,2); hold on;
                mesh(rand_X,rand_Y, magnetic_inc_rand);
                plot3(rand_X,rand_Y, magnetic_inc_rand,'b.','MarkerFaceColor','b','MarkerSize',1);
                xlabel('纬度'); ylabel('经度'); zlabel('磁倾角 (degrees)');
                title('磁倾角三维图(真实值)'); view(3); colorbar;
                if d.CancelRequested, close(d); return; end
                
                d.Message = '正在保存含噪地磁数据 (CSV)...';
                writematrix(magnetic_mod_noisy,"magnetic_mod_noisy_curve_n.csv");
                writematrix(magnetic_dec_noisy,"magnetic_dec_noisy_curve_n.csv");
                writematrix(magnetic_inc_noisy,"magnetic_inc_noisy_curve_n.csv");
                
                close(d); % 所有操作完成，关闭进度条
            else
                % 如果 app.flag2 不是 3，则执行这里的逻辑
                % (确保如果这里前面有其他可能创建 d 的分支，也正确处理 d)
                m=sprintf('请先生成轨迹！'); % 或者根据 app.flag2 的具体含义给出不同提示
                msgbox(m,"注意");
            end

        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            help=sprintf(['1.输入经度纬度高度及相关参数，点击开始计算，即可计算出该位置的磁分量，模值，磁偏角等数据\n\n' ...
                '2.点击生成三维图，即可查看经纬度范围内的地磁总量，磁偏角，磁倾角三维图。点击生成平面图即可查看平面图\n\n' ...
                '3.输入噪声，增加噪声后，可以生成模拟失真后的三维图，最后生成数据集\n\n' ...
                '本系统引用了IGRF地磁模型，详情请参考：\n' ...
                'Drew Compston (2030). International Geomagnetic Reference Field (IGRF) Model (https://www.mathworks.com/matlabcentral/fileexchange/34388-international-geomagnetic-reference-field-igrf-model), MATLAB Central File Exchange. 检索来源 2024/2/20.']);
            msgbox(help,"帮助") 
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            choice=questdlg('确定要退出吗？', '退出确认', '是', '否', '否');
            switch choice 
                case'是'
                    delete(app.UIFigure);
                case'否'
            end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            a=sprintf('Global Geomagnetic Navigation Mapping System \n开发人员：\n');
            msgbox(a,'关于和联系我们');
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.flag==2
                 writematrix(app.lat_curve,"lat_curve.csv");
                 writematrix(app.lon_curve,"lon_curve.csv");
            else        
                    m=sprintf('请先生成轨迹！');
                    msgbox(m,"注意");
            end        
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            
             if app.flag==1
               writematrix(app.rand_x_linear,"lat_linear.csv");
               writematrix(app.rand_y_linear,"lon_linear.csv");
            else        
                    m=sprintf('请先生成轨迹！');
                    msgbox(m,"注意");
            end                   
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.flag2==3
                writematrix(app.lat_curve,"lat_curve_n.csv");
                writematrix(app.lon_curve,"lon_curve_n.csv");
            else        
                    m=sprintf('请先生成轨迹！');
                    msgbox(m,"注意");
            end        


        end

        % Callback function
        function EditField_11ValueChanged(app, event)
           app.year = app.EditField_11.Value;
            
        end

        % Callback function
        function EditField_12ValueChanged(app, event)
            app.month = app.EditField_12.Value;
            
        end

        % Callback function
        function EditField_13ValueChanged(app, event)
            app.day = app.EditField_13.Value;
            
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '关于';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '帮助';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '退出';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '全局';
            app.nTLabel = uilabel(app.Panel);
            app.nTLabel.Text = 'nT';
            app.nTLabel_2 = uilabel(app.Panel);
            app.nTLabel_2.Text = 'nT';
            app.nTLabel_3 = uilabel(app.Panel);
            app.nTLabel_3.Text = 'nT';
            app.nTLabel_4 = uilabel(app.Panel);
            app.nTLabel_4.Text = 'nT';
            app.degreesLabel = uilabel(app.Panel);
            app.degreesLabel.Text = 'degrees';
            app.degreesLabel_2 = uilabel(app.Panel);
            app.degreesLabel_2.Text = 'degrees';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '增加噪声';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '生成三维图';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '生成平面图';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '模值';
            app.TextArea_1 = uitextarea(app.Panel);
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '北向分量';
            app.TextArea_2 = uitextarea(app.Panel);
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '东向分量';
            app.TextArea_3 = uitextarea(app.Panel);
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '天向分量';
            app.TextArea_4 = uitextarea(app.Panel);
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = '磁倾角';
            app.TextArea_5 = uitextarea(app.Panel);
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '磁航向';
            app.TextArea_6 = uitextarea(app.Panel);
            app.calculateButton = uibutton(app.Panel, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Text = '%';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '经度';
            app.ta1 = uieditfield(app.Panel, 'numeric');
            app.ta1.ValueChangedFcn = createCallbackFcn(app, @ta1ValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '纬度';
            app.ta2 = uieditfield(app.Panel, 'numeric');
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '高度';
            app.ta3 = uieditfield(app.Panel, 'numeric');
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '经度分辨率';
            app.ta4 = uieditfield(app.Panel, 'numeric');
            app.ta4.Value = 36;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '纬度分辨率';
            app.ta5 = uieditfield(app.Panel, 'numeric');
            app.ta5.Value = 18;
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '经度范围';
            app.ta6 = uieditfield(app.Panel, 'numeric');
            app.ta6.Value = 360;
            app.ta7 = uieditfield(app.Panel, 'numeric');
            app.ta7.Value = 180;
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '纬度范围';
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = '噪声强度';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '生成数据集';
            app.Label_24 = uilabel(app.Panel);
            app.Label_24.Text = '日期选择';
            app.DatePicker = uidatepicker(app.Panel);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2024 12 31]);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '非随机轨迹生成';
            app.Label_17 = uilabel(app.Panel_2);
            app.Label_17.Text = '起始纬度';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Label_18 = uilabel(app.Panel_2);
            app.Label_18.Text = '起始经度';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '生成';
            app.EditField_6Label = uilabel(app.Panel_2);
            app.EditField_6Label.Text = '曲线复杂度';
            app.EditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.Label_21 = uilabel(app.Panel_2);
            app.Label_21.Text = '结束纬度';
            app.EditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.Label_22 = uilabel(app.Panel_2);
            app.Label_22.Text = '结束经度';
            app.EditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '增加噪声';
            app.Label_23 = uilabel(app.Panel_2);
            app.Label_23.Text = '%';
            app.EditField_9 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_4Label_2 = uilabel(app.Panel_2);
            app.EditField_4Label_2.Text = '噪声强度';
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '保存轨迹';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '随机轨迹生成';
            app.Button_6 = uibutton(app.Panel_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '折线轨迹';
            app.Button_7 = uibutton(app.Panel_3, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '曲线轨迹';
            app.Button_8 = uibutton(app.Panel_3, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '增加噪声';
            app.Label_19 = uilabel(app.Panel_3);
            app.Label_19.Text = '%';
            app.EditField_4Label = uilabel(app.Panel_3);
            app.EditField_4Label.Text = '噪声强度';
            app.EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.Label_20 = uilabel(app.Panel_3);
            app.Label_20.Text = '曲线复杂度';
            app.EditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.Button_10 = uibutton(app.Panel_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '保存轨迹';
            app.Button_12 = uibutton(app.Panel_3, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '保存轨迹';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GMNMS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Global_Geomagnetic_Navigation_Mapping_System)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end